classdef VicsekDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Axes                     matlab.ui.control.UIAxes
        Slider_3Label            matlab.ui.control.Label
        RhoSlide                 matlab.ui.control.Slider
        Slider_2Label            matlab.ui.control.Label
        EtaSlide                 matlab.ui.control.Slider
        SliderLabel              matlab.ui.control.Label
        NuSlide                  matlab.ui.control.Slider
        FreezeButton             matlab.ui.control.Button
        Vicseksimulatorv01Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Frozen % Whether the app is currently in a frozen state
        Field % The current state of the Vicsec field
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Static parameters 
            fieldWidth = 25;
            fieldHeight = 25;
            R = 1;
            dt = 0.1;
            
            % Adjustable parameters will be updated automatically, stored
            % in the appropriate slider structures.
            
            app.Field = VicsekField(fieldWidth,fieldHeight,R,dt);
            app.Field = app.Field.changeRho(app.RhoSlide.Value);
            app.Field.drawFieldFast(app.Axes);
            
            app.Frozen = false;
            
            while ~app.Frozen
                app.Field = app.Field.changeRho(app.RhoSlide.Value);
                app.Field = app.Field.stepModel(app.EtaSlide.Value,app.NuSlide.Value);
                app.Field.drawFieldFast(app.Axes);
                drawnow
            end
        end

        % Button pushed function: FreezeButton
        function FreezeButtonPushed(app, event)
            if app.Frozen
                app.Frozen = false;
                app.FreezeButton.Text = 'Freeze';
                
                while ~app.Frozen
                    app.Field = app.Field.changeRho(app.RhoSlide.Value);
                    app.Field = app.Field.stepModel(app.EtaSlide.Value,app.NuSlide.Value);
                    app.Field.drawFieldFast(app.Axes);
                    drawnow
                end
            else
                app.Frozen = true;
                app.FreezeButton.Text = 'Unfreeze';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, '')
            xlabel(app.Axes, '')
            ylabel(app.Axes, '')
            app.Axes.XLim = [0 25];
            app.Axes.YLim = [0 25];
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'ρ';
            app.RhoSlide = uislider(app.UIFigure);
            app.RhoSlide.Limits = [0 1];
            app.RhoSlide.Value = 0.5;
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'η';
            app.EtaSlide = uislider(app.UIFigure);
            app.EtaSlide.Limits = [0 2];
            app.EtaSlide.Value = 0.5;
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '	ν';
            app.NuSlide = uislider(app.UIFigure);
            app.NuSlide.Limits = [0 1];
            app.NuSlide.Value = 0.5;
            app.FreezeButton = uibutton(app.UIFigure, 'push');
            app.FreezeButton.ButtonPushedFcn = createCallbackFcn(app, @FreezeButtonPushed, true);
            app.FreezeButton.Text = 'Freeze';
            app.Vicseksimulatorv01Label = uilabel(app.UIFigure);
            app.Vicseksimulatorv01Label.Text = 'Vicsek simulator v0.1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VicsekDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end